classdef calibration_app_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        UIAxes_mic_rec        matlab.ui.control.UIAxes
        ListBoxLabel          matlab.ui.control.Label
        ListBox_stimuli       matlab.ui.control.ListBox
        UIAxes_spec_analysis  matlab.ui.control.UIAxes
        UIAxes_stimuli_ch1    matlab.ui.control.UIAxes
        MicrophoneSensitivitymVPaEditFieldLabel  matlab.ui.control.Label
        Mic_Sen_val           matlab.ui.control.EditField
        RMSLabel              matlab.ui.control.Label
        Label_rmsval          matlab.ui.control.Label
        Label_splval          matlab.ui.control.Label
        dBSPLLabel            matlab.ui.control.Label
        StimulateButton       matlab.ui.control.Button
        LoadCalFileButton     matlab.ui.control.Button
        dBSPLEditFieldLabel   matlab.ui.control.Label
        dB_SPL_val            matlab.ui.control.EditField
        UIAxes_stimuli_ch2    matlab.ui.control.UIAxes
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global hfig_calibration_app
            
            try
                release(hfig_stimprog.nidaq.s)
            end
            
            srate = 90000;
            % setup nidaq---------------------------------------------
            hfig_calibration_app.nidaq.srate = srate;
            hfig_calibration_app.nidaq.s = daq.createSession('ni');
            hfig_calibration_app.nidaq.s.Rate = hfig_calibration_app.nidaq.srate;
            addAnalogOutputChannel(hfig_calibration_app.nidaq.s,'Dev1',[0,1],'Voltage');
            
            %data capture
            active_chan_input = [2];
            ch = addAnalogInputChannel(hfig_calibration_app.nidaq.s, 'Dev1', active_chan_input, 'Voltage');
            %ch.Range = [-5,5];
            % ---------------------------------------------------------
            
            list = {'FL','HI', 'HI_Natural'};
            [indx,tf] = listdlg('ListString',list,'SelectionMode','single');
            
            if indx ==1
                stim_wav_dir = 'C:\Users\Lee Lab\Documents\MATLAB\StimProgV6\stimuli wav_94ksrate\';
                hfig_calibration_app.pop = "FL";
            elseif indx ==2
                stim_wav_dir = 'C:\Users\Lee Lab\Documents\MATLAB\StimProgV6\HI stimuli wav\';
                hfig_calibration_app.pop = "HI";
            else
                stim_wav_dir = 'C:\Users\Lee Lab\Documents\MATLAB\StimProgV6\HI Natural stimuli wav\';
                hfig_calibration_app.pop = "HI_Natural";
            end
            
            stim_wav_dir_folders = dir(stim_wav_dir);
            stim_wav_dir_folders = stim_wav_dir_folders(3:end);
            stim_folder_names = {stim_wav_dir_folders.name};
            
            hfig_calibration_app.current_stim.stim_wav_dir_folder_names = stim_folder_names;
            hfig_calibration_app.current_stim.stim_wav_dir = stim_wav_dir;
            
            set(app.ListBox_stimuli, 'Items', stim_folder_names);
            set(app.dB_SPL_val, 'Value', num2str(80))
            set(app.Mic_Sen_val, 'Value', num2str(0.783));
        end

        % Value changed function: ListBox_stimuli
        function ListBox_stimuliValueChanged(app, event)
            global hfig_calibration_app
            
            [~,index] = ismember(app.ListBox_stimuli.Value, app.ListBox_stimuli.Items)
            
            stim_folder = hfig_calibration_app.current_stim.stim_wav_dir_folder_names(index);
            stim_dir = hfig_calibration_app.current_stim.stim_wav_dir;
            
            configuration = load(strcat(stim_dir, char(stim_folder), '\stim_config_', char(stim_folder), '.mat'));
            
            hfig_calibration_app.current_stim.configuration = configuration.configuration;
            
            files = dir(fullfile(strcat(stim_dir, char(stim_folder)), 'signal*.mat'));
            
            for i = 1:length(files)
                %hfig_stimprog.current_stim.signal{i} = audioread(strcat(stim_dir, char(stim_folder), '\', files(i).name));
                imported_signal = load(strcat(stim_dir, char(stim_folder), '\', files(i).name));
                
                if isfield(imported_signal, 'adjusted_signal')
                    temp = load(strcat(stim_dir, char(stim_folder), '\', files(i).name));
                    hfig_calibration_app.current_stim.signal{i} = temp.adjusted_signal;
                elseif isfield(imported_signal, 'silence_signal')
                    temp = load(strcat(stim_dir, char(stim_folder), '\', files(i).name));
                    hfig_calibration_app.current_stim.signal{i} = temp.silence_signal';
                end
                
            end
            
            
            srate = 90000;
            time = 1/srate:1/srate:length(hfig_calibration_app.current_stim.signal{1})/srate;
            
            %Plot Stimuli
            
            plot(app.UIAxes_stimuli_ch1, time, hfig_calibration_app.current_stim.signal{1}, 'b');
            plot(app.UIAxes_stimuli_ch2, time, hfig_calibration_app.current_stim.signal{2}, 'r');
            
            current_stim = char(stim_folder);
            
            if strcmp(current_stim(1),'L')
                set(app.dB_SPL_val, 'Value', num2str(configuration.configuration{1,4}))
            elseif strcmp(current_stim(1),'R')
                set(app.dB_SPL_val, 'Value', num2str(configuration.configuration{2,4}))
            end
            
        end

        % Button pushed function: StimulateButton
        function StimulateButtonPushed(app, event)
            global hfig_calibration_app
            
            cla(app.UIAxes_mic_rec)
            srate = hfig_calibration_app.nidaq.srate;
            
            playback = hfig_calibration_app.current_stim.signal;
            new_playback(:,1) = hfig_calibration_app.current_stim.signal{:,1};
            new_playback(:,2) = hfig_calibration_app.current_stim.signal{:,2};
            
            queueOutputData(hfig_calibration_app.nidaq.s, new_playback);
            output = startForeground(hfig_calibration_app.nidaq.s);
            
            [row col] = size(output);
            
            time_data = 1/hfig_calibration_app.nidaq.srate:1/hfig_calibration_app.nidaq.srate:row/hfig_calibration_app.nidaq.srate;
            
            output = output(:,1)*1000; % multiply by 1000 to covert to mV
            %output = highpass(output,300,srate);
            
            plot(app.UIAxes_mic_rec, time_data, output(:,1), 'b');
            xlim(app.UIAxes_mic_rec, [0, time_data(end)])
            
            %Measure RMS of recording
            srate = hfig_calibration_app.nidaq.srate;
            start = srate*0.0275;
            stop = srate*(0.0275+0.05);
            %xline(app.UIAxes_mic_rec,start/srate);
            %xline(app.UIAxes_mic_rec,stop/srate);
            
            output = output(:,1)/100; % to remove 100x amplification.
            
            % RMS calculation depending on if the signal is call/tone or noise.
            
            %rms_recording = ((max(output)-min(output))/2)*0.707;
            %rms_recording = rms(output(start:stop));
            rms_recording = rms(output);
            set(app.Label_rmsval, 'Text', num2str(rms_recording))
            
            mic_sen = str2num(get(app.Mic_Sen_val, 'Value'));
            dB_difference = 20*log10(rms_recording/mic_sen);
            
            dB_SPL = 94+(dB_difference);
            
            set(app.Label_splval, 'Text', num2str(dB_SPL));
            
            srate = hfig_calibration_app.nidaq.srate;
            nfft = 5000;
            overlap = 50;
            f_freq = 0:50:50000;
            
            [pxx freq] = pwelch((output(:,1)), nfft, overlap, f_freq, srate);
            pxx = 20*log10(abs(pxx));
            plot(app.UIAxes_spec_analysis, freq, pxx)
            
            % y_axis_limit = str2num(get(handles.edit_plot_axis_limit, 'string'));
            % ylim(handles.axes_response_2, [-y_axis_limit, y_axis_limit])
            
            drawnow
            
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes_mic_rec = uiaxes(app.UIFigure);
            app.ListBoxLabel = uilabel(app.UIFigure);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox_stimuli = uilistbox(app.UIFigure);
            app.ListBox_stimuli.ValueChangedFcn = createCallbackFcn(app, @ListBox_stimuliValueChanged, true);
            app.UIAxes_spec_analysis = uiaxes(app.UIFigure);
            app.UIAxes_stimuli_ch1 = uiaxes(app.UIFigure);
            app.MicrophoneSensitivitymVPaEditFieldLabel = uilabel(app.UIFigure);
            app.MicrophoneSensitivitymVPaEditFieldLabel.Text = 'Microphone Sensitivity (mV/Pa)';
            app.Mic_Sen_val = uieditfield(app.UIFigure, 'text');
            app.RMSLabel = uilabel(app.UIFigure);
            app.RMSLabel.Text = 'RMS:';
            app.Label_rmsval = uilabel(app.UIFigure);
            app.Label_rmsval.Text = 'Test';
            app.Label_splval = uilabel(app.UIFigure);
            app.Label_splval.Text = 'Test';
            app.dBSPLLabel = uilabel(app.UIFigure);
            app.dBSPLLabel.Text = 'dB SPL:';
            app.StimulateButton = uibutton(app.UIFigure, 'push');
            app.StimulateButton.ButtonPushedFcn = createCallbackFcn(app, @StimulateButtonPushed, true);
            app.StimulateButton.Text = 'Stimulate';
            app.LoadCalFileButton = uibutton(app.UIFigure, 'push');
            app.LoadCalFileButton.Text = 'Load Cal File';
            app.dBSPLEditFieldLabel = uilabel(app.UIFigure);
            app.dBSPLEditFieldLabel.Text = 'dB SPL';
            app.dB_SPL_val = uieditfield(app.UIFigure, 'text');
            app.UIAxes_stimuli_ch2 = uiaxes(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = calibration_app_v2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end